classdef main < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        TabGroup                   matlab.ui.container.TabGroup
        KonvolusiTab               matlab.ui.container.Tab
        TestLabel                  matlab.ui.control.Label
        KonvolusiBlurButton        matlab.ui.control.Button
        PilihKonvolusiLabel        matlab.ui.control.Label
        PilihFileKonvolusiButton   matlab.ui.control.Button
        SmoothingTab               matlab.ui.container.Tab
        BLPFButton                 matlab.ui.control.Button
        GLPFButton                 matlab.ui.control.Button
        SpasialGaussianBlurButton  matlab.ui.control.Button
        ILPFButton                 matlab.ui.control.Button
        SpasialMeanBlurButton      matlab.ui.control.Button
        PilihSmoothingLabel        matlab.ui.control.Label
        PilihFileSmoothing         matlab.ui.control.Button
        FrekuensiTab               matlab.ui.container.Tab
        LightenTab                 matlab.ui.container.Tab
        MedianTab                  matlab.ui.container.Tab
        PeriodikTab                matlab.ui.container.Tab
    end

    methods (Access = public)
        function res = Konvolusi(~, image, kernel)
            [numRow, numColumn, numColor] = size(image);
            res = zeros([numRow, numColumn, numColor]);
            % Hard coded due matlab issues
            [kernelSize, ~] = size(kernel);
            offsetK = floor(kernelSize / 2);

            boundLower = 1 + offsetK;
            rowUpper = numRow - offsetK;
            colUpper = numColumn - offsetK;

            for i = boundLower:rowUpper
                for j = boundLower:colUpper
                    for c = 1:numColor
                        temp = single(0);
                        for a = -offsetK:offsetK
                            for b = -offsetK:offsetK
                                temp = temp + single(image(i+a, j+b, c)) * kernel(1+offsetK+a, 1+offsetK+b);
                            end
                        end
                        res(i, j, c) = temp;
                        res(i, j, c) = max(0, min(255, res(i, j, c)));
                    end
                end
            end

            res = uint8(res);
        end

        function res = KonvolusiBoxBlur(comp, image)
            kernel = [0.2 0.12 0.12; 0.12 0.12 0.12; 0.12 0.12 0.12];
            res = comp.Konvolusi(image, kernel, 3);
        end

        function res = KonvolusiGaussBlur(~, image)
            % 3x3 Gauss Blur Kernel
            [numRow, numColumn, numColor] = size(image);
            res = zeros([numRow, numColumn, numColor]);
            for i = 2:numRow-1
                for j = 2:numColumn-1
                    for c = 1:numColor
                        temp = 0;
                        for a = -1:1
                            for b = -1:1
                                temp = temp + image(i + a, j + b, c)/9;
                            end
                        end
                        res(i, j, c) = temp;
                        res(i, j, c) = max(0, min(255, res(i, j, c)));
                    end
                end
            end
            
            res = uint8(res);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            comp.TestLabel.Text = string(idivide(uint8(3), 2));
        end

        % Button pushed function: PilihFileKonvolusiButton
        function KonvolusiFileButton(comp, event)
            % preventing main GUI from minimizing after uigetfile
            dummyWindow = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);

            [file, path] = uigetfile({'*.png;*.jpg;*.jpeg;*.tif;*.bmp'});
            if file ~= 0
                comp.PilihKonvolusiLabel.Text = strcat(path, file);
            end
            delete(dummyWindow);
        end

        % Button pushed function: KonvolusiBlurButton
        function JalankanKonvolusi(comp, event)
            try
                image = imread(comp.PilihKonvolusiLabel.Text);
                
                % Initial image
                figure("Name", "Citra Input")
                imshow(image);

                comp.TestLabel.Text = string(size(image));
                % Konvolusi
                kernel = [0.12 0.12 0.12; 0.12 0.12 0.12; 0.12 0.12 0.12];
                res = comp.Konvolusi(image, kernel);
                figure("Name", "Citra Hasil")
                imshow(res);
            catch e
                msgbox(e.message, "Error", "error");
            end
        end

        % Button pushed function: PilihFileSmoothing
        function SmoothingButton(comp, event)
            % preventing main GUI from minimizing after uigetfile
            dummyWindow = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);

            [file, path] = uigetfile({'*.png;*.jpg;*.jpeg;*.tif;*.bmp'});
            if file ~= 0
                comp.PilihSmoothingLabel.Text = strcat(path, file);
            end
            delete(dummyWindow);
        end

        % Button pushed function: SpasialMeanBlurButton
        function JalankanMeanFilter(comp, event)
            try
                image = imread(comp.PilihSmoothingLabel.Text);
             
                % Initial image
                figure("Name", "Citra Input")
                imshow(image);

                % Konvolusi
                res = comp.KonvolusiBoxBlur(image);
                figure("Name", "Citra Hasil")
                imshow(res);
            catch e
                msgbox(e.message, "Error", "error");
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 506 373];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.TabGroup = uitabgroup(comp);
            comp.KonvolusiTab = uitab(comp.TabGroup);
            comp.KonvolusiTab.Title = 'Konvolusi';
            comp.PilihFileKonvolusiButton = uibutton(comp.KonvolusiTab, 'push');
            comp.PilihFileKonvolusiButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @KonvolusiFileButton, true);
            comp.PilihFileKonvolusiButton.Text = 'Pilih File';
            comp.PilihKonvolusiLabel = uilabel(comp.KonvolusiTab);
            comp.PilihKonvolusiLabel.Text = 'Pilih gambar input....';
            comp.KonvolusiBlurButton = uibutton(comp.KonvolusiTab, 'push');
            comp.KonvolusiBlurButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @JalankanKonvolusi, true);
            comp.KonvolusiBlurButton.Text = 'Konvolusi Blur';
            comp.TestLabel = uilabel(comp.KonvolusiTab);
            comp.TestLabel.Text = 'Test';
            comp.SmoothingTab = uitab(comp.TabGroup);
            comp.SmoothingTab.Title = 'Smoothing';
            comp.PilihFileSmoothing = uibutton(comp.SmoothingTab, 'push');
            comp.PilihFileSmoothing.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SmoothingButton, true);
            comp.PilihFileSmoothing.Text = 'Pilih File';
            comp.PilihSmoothingLabel = uilabel(comp.SmoothingTab);
            comp.PilihSmoothingLabel.Text = 'Pilih gambar input....';
            comp.SpasialMeanBlurButton = uibutton(comp.SmoothingTab, 'push');
            comp.SpasialMeanBlurButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @JalankanMeanFilter, true);
            comp.SpasialMeanBlurButton.Text = 'Spasial Mean Blur';
            comp.ILPFButton = uibutton(comp.SmoothingTab, 'push');
            comp.ILPFButton.Text = 'ILPF';
            comp.SpasialGaussianBlurButton = uibutton(comp.SmoothingTab, 'push');
            comp.SpasialGaussianBlurButton.Text = 'Spasial Gaussian Blur';
            comp.GLPFButton = uibutton(comp.SmoothingTab, 'push');
            comp.GLPFButton.Text = 'GLPF';
            comp.BLPFButton = uibutton(comp.SmoothingTab, 'push');
            comp.BLPFButton.Text = 'BLPF';
            comp.FrekuensiTab = uitab(comp.TabGroup);
            comp.FrekuensiTab.Title = 'Frekuensi';
            comp.LightenTab = uitab(comp.TabGroup);
            comp.LightenTab.Title = 'Lighten';
            comp.MedianTab = uitab(comp.TabGroup);
            comp.MedianTab.Title = 'Median';
            comp.PeriodikTab = uitab(comp.TabGroup);
            comp.PeriodikTab.Title = 'Periodik';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end